classdef PINN_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        NondimensionalizePanel       matlab.ui.container.Panel
        TEditField                   matlab.ui.control.NumericEditField
        TEditFieldLabel              matlab.ui.control.Label
        UEditField                   matlab.ui.control.NumericEditField
        UEditFieldLabel              matlab.ui.control.Label
        LEditField                   matlab.ui.control.NumericEditField
        LEditFieldLabel              matlab.ui.control.Label
        ExternalBoundaryConditionsPanel  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        BottomButtonGroup            matlab.ui.container.ButtonGroup
        PressureButton_4             matlab.ui.control.RadioButton
        VelocityButton_4             matlab.ui.control.RadioButton
        NoneButton_4                 matlab.ui.control.RadioButton
        TopButtonGroup               matlab.ui.container.ButtonGroup
        PressureButton_3             matlab.ui.control.RadioButton
        VelocityButton_3             matlab.ui.control.RadioButton
        NoneButton_3                 matlab.ui.control.RadioButton
        RightButtonGroup             matlab.ui.container.ButtonGroup
        PressureButton_2             matlab.ui.control.RadioButton
        VelocityButton_2             matlab.ui.control.RadioButton
        NoneButton_2                 matlab.ui.control.RadioButton
        LeftButtonGroup              matlab.ui.container.ButtonGroup
        PressureButton               matlab.ui.control.RadioButton
        VelocityButton               matlab.ui.control.RadioButton
        NoneButton                   matlab.ui.control.RadioButton
        InternalBoundaryConditionBodyDataPanel  matlab.ui.container.Panel
        SurfaceVelocityIncludedDropDown  matlab.ui.control.DropDown
        SurfaceVelocityIncludedDropDownLabel  matlab.ui.control.Label
        BodyDataFolderButton         matlab.ui.control.Button
        VelocityDataPanel            matlab.ui.container.Panel
        dtEditField                  matlab.ui.control.NumericEditField
        dtEditFieldLabel             matlab.ui.control.Label
        LastEditField                matlab.ui.control.NumericEditField
        LastEditFieldLabel           matlab.ui.control.Label
        TemporalFilterDropDown       matlab.ui.control.DropDown
        TemporalFilterDropDownLabel  matlab.ui.control.Label
        VelocityDataFolderButton     matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        RunPanel                     matlab.ui.container.Panel
        RunButton                    matlab.ui.control.Button
        SaveDataPanel                matlab.ui.container.Panel
        SaveDataFolderButton         matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        vel_path
        last
        tempfil
        dt
        t_star
        x_star
        y_star
        U_star
        V_star
        L
        U
        T
        body_path
        surf_vel
        t_body
        x_body
        y_body
        u_body
        v_body
        vn_body
        left_bound
        right_bound
        top_bound
        bottom_bound
        save_path
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VelocityDataFolderButton
        function VelocityDataFolderButtonPushed(app, event)
            app.vel_path = uigetdir('*.*','Select the velocity data folder');
        end

        % Value changed function: LastEditField
        function LastEditFieldValueChanged(app, event)
            app.last = app.LastEditField.Value;
        end

        % Value changed function: TemporalFilterDropDown
        function TemporalFilterDropDownValueChanged(app, event)
            app.tempfil = app.TemporalFilterDropDown.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% read in velocity data
           
            app.t_star = 0:app.dt/app.T:(app.dt/app.T)*(app.last-1);
            app.t_star = app.t_star';

            for i = 1:app.last
               fullvel(:,:,i) = importdata([app.vel_path '\2Dvel_' num2str(i,'%05.0f') '.dat']);
            end

            switch app.tempfil

                case 'Yes' %Smooth velocity field
                for i = 1:size(fullvel,1)
                    if sum(~isnan(squeeze(fullvel(i,3,:)))) > 1 && sum(~isnan(squeeze(fullvel(i,4,:)))) > 1
                        fullvel(i, 3, find(~isnan(squeeze(fullvel(i,3,:))))) = feval(fit(find(~isnan(squeeze(fullvel(i,3,:)))),squeeze(fullvel(i,3,~isnan(squeeze(fullvel(i,3,:))))),'smoothingspline','SmoothingParam',0.05),find(~isnan(squeeze(fullvel(i,3,:))))); 
                        fullvel(i,4,find(~isnan(squeeze(fullvel(i,4,:))))) = feval(fit(find(~isnan(squeeze(fullvel(i,4,:)))),squeeze(fullvel(i,4,~isnan(squeeze(fullvel(i,4,:))))),'smoothingspline','SmoothingParam',0.05),find(~isnan(squeeze(fullvel(i,4,:)))));
                    end
                end

                case 'No'
            end

            app.t_star = app.t_star./app.T;

            for i = 1:size(fullvel,3)
                app.x_star(:,i) = fullvel(:,1,i)./app.L;
                app.y_star(:,i) = fullvel(:,2,i)./app.L;
                app.U_star(:,i) = fullvel(:,3,i)./app.U;
                app.V_star(:,i) = fullvel(:,4,i)./app.U;
            end

            fullvel = [];
          %% read in body data

          app.t_body = app.t_star;
          for i = 1:app.last
              A = importdata([app.body_path '\body_' num2str(i,'%05.0f') '.dat']);
              app.x_body(:,i) = A(:,1);
              app.y_body(:,i) = A(:,2);
              switch app.surf_vel 
                  case 'Yes'
                      app.u_body(:,i) = A(:,3);
                      app.v_body(:,i) = A(:,4);    
              end
          end

        %% blank all points in velocity field within the body/compute velocity on body if necessary

        for i = 1:size(app.x_star,2)
            blankindx = inpolygon(app.x_star(:,i), app.y_star(:,i), app.x_body(:,i), app.y_body(:,i));
            app.U_star(blankindx,i) = NaN;
            app.V_star(blankindx,i) = NaN;
        end
        
        %% Quiver plot of velocity field with blanked body
        
        for i = 1:size(app.x_star,2)
            clf(app.UIAxes,'reset')
            quiver(app.UIAxes, app.x_star(:,i),app.y_star(:,i), app.U_star(:,i), app.V_star(:,i));
            hold(app.UIAxes,'on') 
            scatter(app.UIAxes, app.x_body(:,i), app.y_body(:,i),'k', 'filled')
            hold(app.UIAxes,'off')
            axis(app.UIAxes,'equal')
            title(app.UIAxes, 'Quiver Plot', 'FontSize', 24, 'FontName', 'Times New Roman', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'x', 'FontSize', 24, 'FontName', 'Times New Roman', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'y', 'FontSize', 24, 'FontName', 'Times New Roman', 'Interpreter', 'latex')
            set(app.UIAxes,'FontSize',24);
            set(app.UIAxes, 'Color', 'w');
            drawnow()
        end
        %% Compute velocity normal to the body

        %Inputed coordinates of the fish's body should consist of all
        %points above the midline (right side) followed by all points below
        %the midline (left side). There should be an equal number of points
        %on the left and right side of the body. 

        for i = 1:size(app.x_body,2)
            xl = min(min(app.x_body(:,i)));
            xr = max(max(app.x_body(:,i)));
            xx = linspace(xl, xr, 501);
            BodyRx = app.x_body(1:ceil(size(app.x_body,1)/2),i);
            BodyRy = app.y_body(1:ceil(size(app.y_body,1)/2),i);
        
            xy = [BodyRx, BodyRy];
            xy = sortrows(xy,1); 
        
            [n, bin] = histc(xy(:,2), unique(xy(:,2)));
            multiple = find(n > 1);
            index    = find(ismember(bin, multiple));
            xy(index,:) = [];
        
            [n, bin] = histc(xy(:,1), unique(xy(:,1)));
            multiple = find(n > 1);
            index    = find(ismember(bin, multiple));
            xy(index,:) = [];
        
            yR = interp1(xy(:,1), xy(:,2), xx, 'spline');
        
            BodyLx = app.x_body(ceil(size(app.x_body,1)/2)+1:end,i);
            BodyLy = app.y_body(ceil(size(app.y_body,1)/2)+1:end,i);
        
            xy = [BodyLx, BodyLy];
            xy = sortrows(xy,1); 
        
            [n, bin] = histc(xy(:,2), unique(xy(:,2)));
            multiple = find(n > 1);
            index    = find(ismember(bin, multiple));
            xy(index,:) = [];
        
            [n, bin] = histc(xy(:,1), unique(xy(:,1)));
            multiple = find(n > 1);
            index    = find(ismember(bin, multiple));
            xy(index,:) = [];
        
            yL = interp1(xy(:,1), xy(:,2), xx, 'spline');
        
            x(:,i) = [xx xx];
            y(:,i) = [yL yR];
            
            switch app.surf_vel
                case 'No'
                    u(:,i) = griddata(app.x_star(~isnan(app.U_star(:,i)),i), app.y_star(~isnan(app.U_star(:,i)),i), app.U_star(~isnan(app.U_star(:,i)),i), x(:,i), y(:,i),'nearest');
                    v(:,i) = griddata(app.x_star(~isnan(app.U_star(:,i)),i), app.y_star(~isnan(app.U_star(:,i)),i), app.V_star(~isnan(app.U_star(:,i)),i), x(:,i), y(:,i),'nearest');
            
                case 'Yes'
                    u(:,i) = griddata(app.x_body(:,i),app.y_body(:,i), app.u_body(:,i), x(:,i), y(:,i),'nearest');
                    v(:,i) = griddata(app.x_body(:,i),app.y_body(:,i), app.v_body(:,i), x(:,i), y(:,i),'nearest');
            end
 
            clear yL yR BodyRx BodyRy BodyLx BodyLy xy 
        end

        for j = 1:size(x,2)
            for i = 1:size(x,1)-1
                if i == 501
                    continue
                end
                dx = x(i,j) - x(i+1,j);
                dy = y(i,j) - y(i+1,j);
                xmid(i,j) = (x(i+1,j) + x(i,j))/2;
                ymid(i,j) = (y(i+1,j) + y(i,j))/2;
                umid(i,j) = (u(i+1,j) + u(i,j))/2;
                vmid(i,j) = (v(i+1,j) + v(i,j))/2;
                mag = sqrt(dx^2 + dy^2);
                n_x(i,j) = dx/mag;
                n_y(i,j) = dy/mag; 
            end
        end
        
        nx = n_y;
        ny = -n_x;

        if ny(200,1) >= 0
            ny(1:500,:) = -ny(1:500,:);
            nx(1:500,:) = -nx(1:500,:);
        elseif ny(700,1) <= 0
            ny(502:end,:) = -ny(502:end,:);
            nx(502:end,:) = -nx(502:end,:);
        end
        
        xmid(501,:) = [];
        ymid(501,:) = [];
        umid(501,:) = [];
        vmid(501,:) = [];
        ny(501,:) = [];
        nx(501,:) = [];
        
        xmid = [x(1,:);xmid;x(end,:)];
        ymid = [y(1,:);ymid; y(end,:)];
        umid = [u(1,:);umid; u(end,:)];
        vmid = [u(1,:);vmid; u(end,:)];
        nx = [-1*ones(1,size(n_x,2)); nx; 1*ones(1,size(n_x,2))];
        ny = [zeros(1,size(n_x,2)); ny; zeros(1,size(n_x,2)) ];

        umid(1,:) = 0;
        umid(end,:) = 0;
        vnorm = umid.*nx + vmid.*ny;
        app.t_body = app.t_star;
  
        for i= 1:size(app.x_star,2)
            clf(app.UIAxes2,'reset')
            scatter(app.UIAxes2, xmid(:,i), ymid(:,i),'k','filled'); 
            hold(app.UIAxes2,'on')
            quiver(app.UIAxes2, xmid(:,i), ymid(:,i), nx(:,i), ny(:,i),0.5);
            axis(app.UIAxes2, 'equal') 
            hold(app.UIAxes2,'off') 
            title(app.UIAxes2, 'Fish Body w/ Norm Vel Vectors', 'FontSize', 24, 'FontName', 'Times New Roman', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'x', 'FontSize', 24, 'FontName', 'Times New Roman', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'y', 'FontSize', 24, 'FontName', 'Times New Roman', 'Interpreter', 'latex')
            set(app.UIAxes2,'FontSize',24);
            set(app.UIAxes2, 'Color', 'w');
            drawnow()
        end
        %% Enforce BCS

        x_bounds_v = zeros(1,size(app.x_star,2));
        y_bounds_v = zeros(1,size(app.x_star,2));
        u_bounds = zeros(1,size(app.x_star,2));
        v_bounds = zeros(1,size(app.x_star,2));
        x_bounds_p = zeros(1,size(app.x_star,2));
        y_bounds_p = zeros(1,size(app.x_star,2));
        
        switch app.left_bound.Text
            case 'Velocity'
                row = find(app.x_star(:,1) == min(app.x_star(:,1)));
                x_bounds_v = [x_bounds_v; app.x_star(row,:)];
                y_bounds_v = [y_bounds_v; app.y_star(row,:)];
                u_bounds = [u_bounds; app.U_star(row,:)];
                v_bounds = [v_bounds; app.V_star(row,:)];
            case 'Pressure'
                row = find(app.x_star(:,1) == min(app.x_star(:,1)));
                x_bounds_p = [x_bounds_p; app.x_star(row,:)];
                y_bounds_p = [y_bounds_p; app.y_star(row,:)];
            case 'None'
        end

        switch app.right_bound.Text
            case 'Velocity'
                row = find(app.x_star(:,1) == max(app.x_star(:,1)));
                x_bounds_v = [x_bounds_v; app.x_star(row,:)];
                y_bounds_v = [y_bounds_v; app.y_star(row,:)];
                u_bounds = [u_bounds; app.U_star(row,:)];
                v_bounds = [v_bounds; app.V_star(row,:)];
            case 'Pressure'
                row = find(app.x_star(:,1) == max(app.x_star(:,1)));
                x_bounds_p = [x_bounds_p; app.x_star(row,:)];
                y_bounds_p = [y_bounds_p; app.y_star(row,:)];
            case 'None'
        end

        switch app.top_bound.Text
            case 'Velocity'
                row = find(app.y_star(:,1) == max(app.y_star(:,1)));
                x_bounds_v = [x_bounds_v; app.x_star(row,:)];
                y_bounds_v = [y_bounds_v; app.y_star(row,:)];
                u_bounds = [u_bounds; app.U_star(row,:)];
                v_bounds = [v_bounds; app.V_star(row,:)];
            case 'Pressure'
                row = find(app.y_star(:,1) == max(app.y_star(:,1)));
                x_bounds_p = [x_bounds_p; app.x_star(row,:)];
                y_bounds_p = [y_bounds_p; app.y_star(row,:)];
            case 'None'
        end

        switch app.bottom_bound.Text
            case 'Velocity'
                row = find(app.y_star(:,1) == min(app.y_star(:,1)));
                x_bounds_v = [x_bounds_v; app.x_star(row,:)];
                y_bounds_v = [y_bounds_v; app.y_star(row,:)];
                u_bounds = [u_bounds; app.U_star(row,:)];
                v_bounds = [v_bounds; app.V_star(row,:)];
            case 'Pressure'
                row = find(app.y_star(:,1) == min(app.y_star(:,1)));
                x_bounds_p = [x_bounds_p; app.x_star(row,:)];
                y_bounds_p = [y_bounds_p; app.y_star(row,:)];
            case 'None'
        end

        x_bounds_v(1,:) = [];
        y_bounds_v(1,:) = [];
        u_bounds(1,:) = [];
        v_bounds(1,:) = [];
        x_bounds_p(1,:) = [];
        y_bounds_p(1,:) = [];
        p_bounds = zeros(size(x_bounds_p));
        t_bounds = app.t_star;

        %% Save Matfiles

        x_star = app.x_star;
        y_star = app.y_star;
        U_star = app.U_star;
        V_star = app.V_star;
        t_star = app.t_star;
        
        save([app.save_path '\2Dvel_data_full.mat'], 'x_star', 'y_star', 't_star', 'U_star', 'V_star');

        dx = abs(x_star(1,1) - x_star(2,1));

        xl = min(min(app.x_body))-dx;
        xr = max(max(app.x_body))+dx;
        yl = min(min(app.y_body))-dx;
        yr = max(max(app.y_body))+dx;

        ind_xl = find(x_star(:,1) > xl);
        ind_xr = find(x_star(:,1) < xr);
        ind_yl = find(y_star(:,1) > yl);
        ind_yr = find(y_star(:,1) < yr);
        
        indx = intersect(ind_xl,ind_xr);
        indy = intersect(ind_yl,ind_yr);
        ind = intersect(indx,indy);

        x_sec = x_star(ind,:);
        y_sec = y_star(ind,:);
        t_sec = t_star;
        U_sec = U_star(ind,:); 
        V_sec = V_star(ind,:);

        x_star(ind,:) = [];
        y_star(ind,:) = [];
        U_star(ind,:) = [];
        V_star(ind,:) = [];

        x_body = app.x_body;
        y_body = app.y_body;
        t_body = app.t_body;

        save([app.save_path '\body_norm_data.mat'], 'xmid', 'ymid', 'vnorm', 't_body', 'nx','ny');
        save([app.save_path '\body_data.mat'], 'x_body', 'y_body');
        save([app.save_path '\2Dvel_data_cut.mat'], 'x_star', 'y_star', 't_star', 'U_star', 'V_star');
        save([app.save_path '\2Dvel_data_sec.mat'], 'x_sec', 'y_sec', 't_sec', 'U_sec', 'V_sec');
        save([app.save_path '\vel_bound_data.mat'], 'x_bounds_v', 'y_bounds_v', 't_bounds', 'u_bounds', 'v_bounds');
        save([app.save_path '\press_bound_data.mat'], 'x_bounds_p', 'y_bounds_p', 't_bounds', 'p_bounds');

        end

        % Value changed function: dtEditField
        function dtEditFieldValueChanged(app, event)
            app.dt = app.dtEditField.Value;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            app.T = app.TEditField.Value;
        end

        % Value changed function: UEditField
        function uEditFieldValueChanged(app, event)
            app.U = app.UEditField.Value;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.L = app.LEditField.Value;
        end

        % Button pushed function: BodyDataFolderButton
        function BodyDataFolderButtonPushed(app, event)
           app.body_path = uigetdir('*.*','Select the body data folder');
        end

        % Value changed function: SurfaceVelocityIncludedDropDown
        function SurfaceVelocityIncludedDropDownValueChanged(app, event)
            app.surf_vel = app.SurfaceVelocityIncludedDropDown.Value;
        end

        % Selection changed function: LeftButtonGroup
        function LeftButtonGroupSelectionChanged(app, event)
            app.left_bound = app.LeftButtonGroup.SelectedObject;
        end

        % Selection changed function: RightButtonGroup
        function RightButtonGroupSelectionChanged(app, event)
            app.right_bound = app.RightButtonGroup.SelectedObject;
        end

        % Selection changed function: TopButtonGroup
        function TopButtonGroupSelectionChanged(app, event)
            app.top_bound = app.TopButtonGroup.SelectedObject;
        end

        % Selection changed function: BottomButtonGroup
        function BottomButtonGroupSelectionChanged(app, event)
            app.bottom_bound = app.BottomButtonGroup.SelectedObject;
        end

        % Button pushed function: SaveDataFolderButton
        function SaveDataFolderButtonPushed(app, event)
            app.save_path = uigetdir('*.*','Select the save folder');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {546, 546};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VelocityDataPanel = uipanel(app.LeftPanel);
            app.VelocityDataPanel.Title = 'Velocity Data';
            app.VelocityDataFolderButton = uibutton(app.VelocityDataPanel, 'push');
            app.VelocityDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityDataFolderButtonPushed, true);
            app.VelocityDataFolderButton.Text = 'Velocity Data Folder';
            app.TemporalFilterDropDownLabel = uilabel(app.VelocityDataPanel);
            app.TemporalFilterDropDownLabel.Text = 'Temporal Filter?';
            app.TemporalFilterDropDown = uidropdown(app.VelocityDataPanel);
            app.TemporalFilterDropDown.Items = {'Select', 'Yes', 'No'};
            app.TemporalFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @TemporalFilterDropDownValueChanged, true);
            app.TemporalFilterDropDown.Value = 'Select';
            app.LastEditFieldLabel = uilabel(app.VelocityDataPanel);
            app.LastEditFieldLabel.Text = 'Last';
            app.LastEditField = uieditfield(app.VelocityDataPanel, 'numeric');
            app.LastEditField.ValueChangedFcn = createCallbackFcn(app, @LastEditFieldValueChanged, true);
            app.dtEditFieldLabel = uilabel(app.VelocityDataPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.VelocityDataPanel, 'numeric');
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @dtEditFieldValueChanged, true);
            app.InternalBoundaryConditionBodyDataPanel = uipanel(app.LeftPanel);
            app.InternalBoundaryConditionBodyDataPanel.Title = 'Internal Boundary Condition (Body Data)';
            app.BodyDataFolderButton = uibutton(app.InternalBoundaryConditionBodyDataPanel, 'push');
            app.BodyDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BodyDataFolderButtonPushed, true);
            app.BodyDataFolderButton.Text = 'Body Data Folder';
            app.SurfaceVelocityIncludedDropDownLabel = uilabel(app.InternalBoundaryConditionBodyDataPanel);
            app.SurfaceVelocityIncludedDropDownLabel.Text = 'Surface Velocity Included';
            app.SurfaceVelocityIncludedDropDown = uidropdown(app.InternalBoundaryConditionBodyDataPanel);
            app.SurfaceVelocityIncludedDropDown.Items = {'Select', 'Yes', 'No'};
            app.SurfaceVelocityIncludedDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceVelocityIncludedDropDownValueChanged, true);
            app.SurfaceVelocityIncludedDropDown.Value = 'Select';
            app.ExternalBoundaryConditionsPanel = uipanel(app.LeftPanel);
            app.ExternalBoundaryConditionsPanel.Title = 'External  Boundary Conditions';
            app.GridLayout2 = uigridlayout(app.ExternalBoundaryConditionsPanel);
            app.LeftButtonGroup = uibuttongroup(app.GridLayout2);
            app.LeftButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftButtonGroupSelectionChanged, true);
            app.LeftButtonGroup.Title = 'Left';
            app.NoneButton = uiradiobutton(app.LeftButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.VelocityButton = uiradiobutton(app.LeftButtonGroup);
            app.VelocityButton.Text = 'Velocity';
            app.PressureButton = uiradiobutton(app.LeftButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.RightButtonGroup = uibuttongroup(app.GridLayout2);
            app.RightButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RightButtonGroupSelectionChanged, true);
            app.RightButtonGroup.Title = 'Right';
            app.NoneButton_2 = uiradiobutton(app.RightButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.VelocityButton_2 = uiradiobutton(app.RightButtonGroup);
            app.VelocityButton_2.Text = 'Velocity';
            app.PressureButton_2 = uiradiobutton(app.RightButtonGroup);
            app.PressureButton_2.Text = 'Pressure';
            app.TopButtonGroup = uibuttongroup(app.GridLayout2);
            app.TopButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TopButtonGroupSelectionChanged, true);
            app.TopButtonGroup.Title = 'Top';
            app.NoneButton_3 = uiradiobutton(app.TopButtonGroup);
            app.NoneButton_3.Text = 'None';
            app.NoneButton_3.Value = true;
            app.VelocityButton_3 = uiradiobutton(app.TopButtonGroup);
            app.VelocityButton_3.Text = 'Velocity';
            app.PressureButton_3 = uiradiobutton(app.TopButtonGroup);
            app.PressureButton_3.Text = 'Pressure';
            app.BottomButtonGroup = uibuttongroup(app.GridLayout2);
            app.BottomButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BottomButtonGroupSelectionChanged, true);
            app.BottomButtonGroup.Title = 'Bottom';
            app.NoneButton_4 = uiradiobutton(app.BottomButtonGroup);
            app.NoneButton_4.Text = 'None';
            app.NoneButton_4.Value = true;
            app.VelocityButton_4 = uiradiobutton(app.BottomButtonGroup);
            app.VelocityButton_4.Text = 'Velocity';
            app.PressureButton_4 = uiradiobutton(app.BottomButtonGroup);
            app.PressureButton_4.Text = 'Pressure';
            app.NondimensionalizePanel = uipanel(app.LeftPanel);
            app.NondimensionalizePanel.Title = 'Non-dimensionalize';
            app.LEditFieldLabel = uilabel(app.NondimensionalizePanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.NondimensionalizePanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.UEditFieldLabel = uilabel(app.NondimensionalizePanel);
            app.UEditFieldLabel.Text = 'U';
            app.UEditField = uieditfield(app.NondimensionalizePanel, 'numeric');
            app.UEditField.ValueChangedFcn = createCallbackFcn(app, @uEditFieldValueChanged, true);
            app.TEditFieldLabel = uilabel(app.NondimensionalizePanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.NondimensionalizePanel, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SaveDataPanel = uipanel(app.RightPanel);
            app.SaveDataPanel.Title = 'Save Data';
            app.SaveDataFolderButton = uibutton(app.SaveDataPanel, 'push');
            app.SaveDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataFolderButtonPushed, true);
            app.SaveDataFolderButton.Text = 'Save Data Folder';
            app.RunPanel = uipanel(app.RightPanel);
            app.RunPanel.Title = 'Run';
            app.RunButton = uibutton(app.RunPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PINN_Input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end